classdef SerialScope_255 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Pause         matlab.ui.control.StateButton
        Lamp          matlab.ui.control.Lamp
        Label_6       matlab.ui.control.Label
        saveData      matlab.ui.control.Button
        TabGroup      matlab.ui.container.TabGroup
        Tab           matlab.ui.container.Tab
        Refresh       matlab.ui.control.Button
        Label         matlab.ui.control.Label
        SerialPort    matlab.ui.control.DropDown
        Tab_2         matlab.ui.container.Tab
        ChannalCount  matlab.ui.control.NumericEditField
        Label_4       matlab.ui.control.Label
        DataBit       matlab.ui.control.NumericEditField
        Label_3       matlab.ui.control.Label
        Label_2       matlab.ui.control.Label
        BaudRate      matlab.ui.control.NumericEditField
        Tab_3         matlab.ui.container.Tab
        GainAva_2     matlab.ui.control.Button
        GainAva       matlab.ui.control.Button
        ChanGain4     matlab.ui.control.NumericEditField
        Label_10      matlab.ui.control.Label
        ChanGain2     matlab.ui.control.NumericEditField
        Label_8       matlab.ui.control.Label
        ChanGain3     matlab.ui.control.NumericEditField
        Label_9       matlab.ui.control.Label
        ChanGain1     matlab.ui.control.NumericEditField
        Label_7       matlab.ui.control.Label
        ChannalSel    matlab.ui.control.DropDown
        Label_5       matlab.ui.control.Label
        ButtonGroup   matlab.ui.container.ButtonGroup
        ClosePort     matlab.ui.control.ToggleButton
        OpenPort      matlab.ui.control.ToggleButton
        UIAxes_2      matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s                    % serialport
        buff = struct('a', []);
        plotcall
        plotSel
        SECn
        t
        TIME_STEP = 1      %时间步长
        Gain
        lost = 0
        
    end
    

    methods (Access = private)
        
        function readData(app,~,~)
            value = read(app.s,app.ChannalCount.Value + 1,"int16");
%             value = readline(app.s);
%             test=str2num(value);%str2double(value);
%             printf(app.test);
            if value(1) ~= 255
                flush(app.s,"input"); 
%                 fprintf("当前数 %d \n",value(1));
                app.lost=app.lost+1;               
            else
% 
%             fprintf("当前数 %d \n",value(2));
%                 if(length(test)==app.ChannalCount.Value)
                    for i = 1:app.ChannalCount.Value
                        app.buff(i).a(end+1) = value(i+1) * app.Gain(i);
                    end
%                 else
%                     for i = 1:app.ChannalCount.Value
%                         app.buff(i).a(end+1) = app.buff(i).a(end);
%                     end
%                     flush(app.s,"input"); 
%                     app.lost=app.lost+1;
%                 end
            end
        end
        
        
        function updateGUI(app,~,~)
            % plot color values in GUI
            for i = 1 : app.ChannalCount.Value
                % (discard the 1-st element, append the new element)
                app.plotcall(i).YData = [app.plotcall(i).YData(length(app.buff(i).a) + 1:end) app.buff(i).a];
%                 app.plotcall(i).YData = [app.plotcall(i).YData(2:end) app.buff(i).a(end)];
                app.plotcall(i).XData = (0:length(app.plotcall(i).YData)-1);
                app.buff(i).a = [];
            end
                app.plotSel.YData = app.plotcall(app.SECn).YData;
                app.plotSel.XData = app.plotcall(app.SECn).XData;
%                 fprintf("当前数 %d \n",app.plotSel.YData(end));
%                 fprintf("测试 \n");x
        end
        
%         function timerFunction(app)
%             app.updateGUI(app);
% %             app.sendData(color_values_sw);
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if license('test', 'instr_control_toolbox')
                p = instrhwinfo('serial');
                app.SerialPort.Items = p.AvailableSerialPorts;
            end
            
%             app.ChannalSel.Items = 1:app.ChannalCount.Value;
            
            app.ButtonGroup.SelectedObject = app.ClosePort;
%             app.buff = 0;
            
            app.UIAxes.XLim = [0 19999];
            app.UIAxes.YLim = [0 3000];
            
            app.UIAxes.XLim = [0 19999];
%             app.UIAxes.YLim = [0 3000];
            
%             time = (0 : app.TIME_STEP : 10);
              time = (0  : 19999);
            
%             time = 0:9999;
            
            app.plotcall = plot(app.UIAxes, time, zeros(length(time), app.ChannalCount.Value), 'LineWidth', 2);
            
            app.plotSel = plot(app.UIAxes_2, time, zeros(length(time),1));
            
            switch app.ChannalCount.Value
                case 1
                    app.ChannalSel.Items = {'1'};
                case 2
                    app.ChannalSel.Items = {'1','2'};
                case 3
                    app.ChannalSel.Items = {'1','2','3'};
                case 4
                    app.ChannalSel.Items = {'1','2','3','4'};
                case 5
                    app.ChannalSel.Items = {'1','2','3','4','5'};
                case 6
                    app.ChannalSel.Items = {'1','2','3','4','5','6'};
            end
            
            app.SECn = 1;
            
            % set up timer
            app.t = timer;
            app.t.TimerFcn = @app.updateGUI;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.TIME_STEP;
            app.t.BusyMode = 'drop';
            
          for i=1:app.ChannalCount.Value
            app.buff(i).a = [] ;
            app.Gain(i) = 1 ;
          end
          
          fprintf(" 初始化完成 \n");

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton
                case app.OpenPort
                    
                    if app.t.running == "off"
                        fprintf('打开串口%s,波特率为 %d \n',app.SerialPort.Value, app.BaudRate.Value);
                        app.s = serialport(app.SerialPort.Value,app.BaudRate.Value,"DataBits",app.DataBit.Value);
%                          ChanCount = 2 * app.ChannalCount.Value;
                        configureTerminator(app.s,255);
%                         configureTerminator(app.s,"CR/LF");
                        
                        flush(app.s);
                        configureCallback(app.s,"terminator",@app.readData);
                        
                        start(app.t);
                        app.Lamp.Color = [0 1 0];
                    end
                    

                otherwise
                    configureCallback(app.s,"off");
                    flush(app.s);
                    stop(app.t);
                    delete(app.s);
                    clear app.s;
                    fprintf('关闭串口\n');
                    app.Lamp.Color = [1 0 0];
                    fprintf("数据丢失警告，共计 %d 次\n",app.lost);
%                      assignin("base",'data',app.test)
            end
        end

        % Value changed function: SerialPort
        function SerialPortValueChanged(app, event)
            COM = app.SerialPort.Value;
            fprintf('当前为串口%s\n',COM);
        end

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            if license('test', 'instr_control_toolbox')
                % delete all serial port objets from MATLAB memory
                % (Instrument Control Toolbox required)
                delete(instrfind);
                % looking for available serial ports
                % (Instrument Control Toolbox required)
                p = instrhwinfo('serial');
                app.SerialPort.Items = p.AvailableSerialPorts;
            end
        end

        % Value changed function: ChannalSel
        function ChannalSelValueChanged(app, event)
            value = app.ChannalSel.Value;
            switch value
                case '1'
                    app.SECn = 1;
                case '2'
                    app.SECn = 2;
                case '3'
                    app.SECn = 3;
                case '4'
                    app.SECn = 4;
                case '5'
                    app.SECn = 5;
                case '6'
                    app.SECn = 6;
            end
            
            app.plotSel.YData = app.plotcall(app.SECn).YData;
            app.plotSel.XData = app.plotcall(app.SECn).XData;
        end

        % Value changed function: ChannalCount
        function ChannalCountValueChanged(app, event)
            value = app.ChannalCount.Value;
            switch value
                case 1
                    app.ChannalSel.Items = {'1'};
                case 2
                    app.ChannalSel.Items = {'1','2'};
                case 3
                    app.ChannalSel.Items = {'1','2','3'};
%                     fprintf("3个通道");
                case 4
                    app.ChannalSel.Items = {'1','2','3','4'};
                case 5
                    app.ChannalSel.Items = {'1','2','3','4','5'};
                case 6
                    app.ChannalSel.Items = {'1','2','3','4','5','6'};
            end
        end

        % Button pushed function: saveData
        function saveDataPushed(app, event)
%            global h;
            assignin("base",'data',app.plotcall)
        end

        % Button pushed function: GainAva
        function GainAvaButtonPushed(app, event)
            app.Gain(1) = app.ChanGain1.Value;
            app.Gain(2) = app.ChanGain2.Value;
            app.Gain(3) = app.ChanGain3.Value;
            app.Gain(4) = app.ChanGain4.Value;
        end

        % Button pushed function: GainAva_2
        function GainAva_2ButtonPushed(app, event)
          for i=1:app.ChannalCount.Value
            app.Gain(i) = 1 ;
          end
        end

        % Value changed function: Pause
        function PauseValueChanged(app, event)
            value = app.Pause.Value;
            if (value)
                stop(app.t);
            else
                start(app.t);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '串口示波')
            xlabel(app.UIAxes, '序列')
            ylabel(app.UIAxes, '幅值')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 9999];
            app.UIAxes.YLim = [-2000 2000];
            app.UIAxes.Tag = 'MainPlot';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '通道')
            xlabel(app.UIAxes_2, '序列')
            ylabel(app.UIAxes_2, '幅值')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 19999];
            app.UIAxes_2.Tag = 'MainPlot';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '控制';
            app.ButtonGroup.Tag = 'Control';
            app.OpenPort = uitogglebutton(app.ButtonGroup);
            app.OpenPort.Text = '打开串口';
            app.OpenPort.Value = true;
            app.ClosePort = uitogglebutton(app.ButtonGroup);
            app.ClosePort.Text = '关闭串口';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '当前通道';
            app.ChannalSel = uidropdown(app.UIFigure);
            app.ChannalSel.Items = {'1', '2', '3', '4', '5', '6'};
            app.ChannalSel.ValueChangedFcn = createCallbackFcn(app, @ChannalSelValueChanged, true);
            app.ChannalSel.Value = '1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '串口';
            app.SerialPort = uidropdown(app.Tab);
            app.SerialPort.Items = {'COM?'};
            app.SerialPort.ValueChangedFcn = createCallbackFcn(app, @SerialPortValueChanged, true);
            app.SerialPort.Tag = 'PotNum';
            app.SerialPort.Value = 'COM?';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '串口号';
            app.Refresh = uibutton(app.Tab, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Text = '刷新';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '协议设置';
            app.BaudRate = uieditfield(app.Tab_2, 'numeric');
            app.BaudRate.ValueDisplayFormat = '%.0f';
            app.BaudRate.Value = 115200;
            app.Label_2 = uilabel(app.Tab_2);
            app.Label_2.Text = '波特率';
            app.Label_3 = uilabel(app.Tab_2);
            app.Label_3.Text = '数据位';
            app.DataBit = uieditfield(app.Tab_2, 'numeric');
            app.DataBit.Value = 8;
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = '通道数';
            app.ChannalCount = uieditfield(app.Tab_2, 'numeric');
            app.ChannalCount.ValueChangedFcn = createCallbackFcn(app, @ChannalCountValueChanged, true);
            app.ChannalCount.Value = 6;
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '通道增益';
            app.Label_7 = uilabel(app.Tab_3);
            app.Label_7.Text = '通道1';
            app.ChanGain1 = uieditfield(app.Tab_3, 'numeric');
            app.ChanGain1.Value = 0.001;
            app.Label_9 = uilabel(app.Tab_3);
            app.Label_9.Text = '通道3';
            app.ChanGain3 = uieditfield(app.Tab_3, 'numeric');
            app.ChanGain3.Value = 0.001;
            app.Label_8 = uilabel(app.Tab_3);
            app.Label_8.Text = '通道2';
            app.ChanGain2 = uieditfield(app.Tab_3, 'numeric');
            app.ChanGain2.Value = 0.001;
            app.Label_10 = uilabel(app.Tab_3);
            app.Label_10.Text = '通道4';
            app.ChanGain4 = uieditfield(app.Tab_3, 'numeric');
            app.ChanGain4.Value = 0.001;
            app.GainAva = uibutton(app.Tab_3, 'push');
            app.GainAva.ButtonPushedFcn = createCallbackFcn(app, @GainAvaButtonPushed, true);
            app.GainAva.Text = '使用增益';
            app.GainAva_2 = uibutton(app.Tab_3, 'push');
            app.GainAva_2.ButtonPushedFcn = createCallbackFcn(app, @GainAva_2ButtonPushed, true);
            app.GainAva_2.Text = '取消增益';
            app.saveData = uibutton(app.UIFigure, 'push');
            app.saveData.ButtonPushedFcn = createCallbackFcn(app, @saveDataPushed, true);
            app.saveData.Text = {'保存数据'; '到workspace'};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '连接指示';
            app.Lamp = uilamp(app.UIFigure);
            app.Pause = uibutton(app.UIFigure, 'state');
            app.Pause.ValueChangedFcn = createCallbackFcn(app, @PauseValueChanged, true);
            app.Pause.Text = '暂停';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialScope_255

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end